classdef GuiTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BLACKJACKMANANDMACHINEPanel  matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        StatusLabel                  matlab.ui.control.Label
        BalanceLabel                 matlab.ui.control.Label
        BetButton                    matlab.ui.control.Button
        BetAmountEditField           matlab.ui.control.NumericEditField
        BetAmountLabel               matlab.ui.control.Label
        RobotStatus                  matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        toggleButton                 matlab.ui.control.StateButton
        HitButton                    matlab.ui.control.Button
        StandButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        currentBalance = 10000
    end
    % properties (Access = private)
    %     BlackjackGameEngine % Description
    %     playerHand    % Player's hand
    %     dealerHand    % Dealer's hand
    %     betAmount     % Current bet amount
    % end
    %
    %
    % methods (Access = private)
    %     % Function to update output in the GUI
    %     function updateOutput(app, message)
    %         app.OutputArea.Value = [app.OutputArea.Value; message];
    %     end
    %
    %     % Wait for player decision (hit/stand)
    %     function waitForPlayerDecision(app)
    %         app.PlayerChoseHit = false;
    %         app.PlayerChoseStand = false;
    %         app.HitButton.Enable = 'on';
    %         app.StandButton.Enable = 'on';
    %         while ~app.PlayerChoseHit && ~app.PlayerChoseStand
    %             pause(0.1);
    %         end
    %         app.HitButton.Enable = 'off';
    %         app.StandButton.Enable = 'off';
    %     end
    %
    %     % Callback for the Hit button
    %     function onHitButtonPressed(app, event)
    %         app.PlayerChoseHit = true;
    %     end
    %
    %     % Callback for the Stand button
    %     function onStandButtonPressed(app, event)
    %         app.PlayerChoseStand = true;
    %     end
    %
    %     % Wait for bet amount input
    %     function waitForBet(app)
    %         while app.betAmount <= 0
    %             pause(0.1);
    %         end
    %     end
    %     % Update bet amount when value changes
    %     function BetInputValueChanged(app, event)
    %         app.betAmount = app.BetInput.Value;  % Retrieve the bet amount
    %     end
    %
    % end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushedCallback(app, event)
            % app.PlayerChoseHit = true;
        end

        % Button pushed function: StandButton
        function StandButtonPushedCallback(app, event)
            % app.PlayerChoseStand = true;
        end

        % Callback function: not associated with a component
        function CurrentBetEditFieldValueChanged(app, event)
            % value = app.CurrentBetEditField.balance;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.toggleButton.Value = false;  % Turn off the E-Stop toggle
            app.toggleButton.Enable = 'on';   % Enable the toggle button
            app.RobotStatus.Text = 'Robot ready to start.';
        end

        % Value changed function: toggleButton
        function toggleButtonValueChanged2(app, event)
            if app.toggleButton.Enable
                value = app.toggleButton.Value; % Get the toggle button value
                % Update the label based on the toggle button's state
                if value  % If the toggle button is activated
                    app.RobotStatus.Text = 'Robot standing-by! Press start to continue';
                else  % If the toggle button is deactivated
                    app.RobotStatus.Text = 'Robot is in motion, caution!';
                end
            else
                % If the toggle is disabled, reset its value to the previous state
                app.toggleButton.Value = false; % Keep it off
            end
        end

        % Button pushed function: BetButton
        function BetButtonPushed(app, event)
            inputAmount = app.BetAmountEditField.Value;

            % Validate the input
            if isnan(inputAmount) || inputAmount < 1 || inputAmount > app.currentBalance
                % Display a message showing the valid range (1 to currentBalance)
                app.StatusLabel.Text = ['Please enter a valid amount between 1 and ', num2str(app.currentBalance)];
            elseif inputAmount > app.currentBalance
                % Check if the input amount is greater than current balance
                app.StatusLabel.Text = 'Insufficient funds.';
            elseif app.currentBalance == 0
                % Check if the balance is already zero
                app.StatusLabel.Text = 'Insufficient funds. You cannot place more bets.';
            else
                % Update the chosen amount label
                app.StatusLabel.Text = ['Chosen Amount: $', num2str(inputAmount)];

                % Subtract the chosen amount from the balance
                app.currentBalance = app.currentBalance - inputAmount;

                % Update the balance label
                app.BalanceLabel.Text = ['Current Balance: $', num2str(app.currentBalance)];
            end
        end

        % Value changed function: BetAmountEditField
        function BetAmountEditFieldValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BLACKJACKMANANDMACHINEPanel = uipanel(app.UIFigure);
            app.BLACKJACKMANANDMACHINEPanel.Title = 'BLACKJACK MAN AND MACHINE';
            app.StandButton = uibutton(app.BLACKJACKMANANDMACHINEPanel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushedCallback, true);
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.BLACKJACKMANANDMACHINEPanel, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushedCallback, true);
            app.HitButton.Text = 'Hit';
            app.toggleButton = uibutton(app.BLACKJACKMANANDMACHINEPanel, 'state');
            app.toggleButton.ValueChangedFcn = createCallbackFcn(app, @toggleButtonValueChanged2, true);
            app.toggleButton.Text = 'E-Stop (Toggle)';
            app.StartButton = uibutton(app.BLACKJACKMANANDMACHINEPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RobotStatus = uilabel(app.BLACKJACKMANANDMACHINEPanel);
            app.RobotStatus.Text = 'Robot is in motion, caution!';
            app.BetAmountLabel = uilabel(app.BLACKJACKMANANDMACHINEPanel);
            app.BetAmountLabel.Text = 'Bet Amount:';
            app.BetAmountEditField = uieditfield(app.BLACKJACKMANANDMACHINEPanel, 'numeric');
            app.BetAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BetAmountEditFieldValueChanged, true);
            app.BetAmountEditField.Value = 1000;
            app.BetButton = uibutton(app.BLACKJACKMANANDMACHINEPanel, 'push');
            app.BetButton.ButtonPushedFcn = createCallbackFcn(app, @BetButtonPushed, true);
            app.BetButton.Text = 'Bet';
            app.BalanceLabel = uilabel(app.BLACKJACKMANANDMACHINEPanel);
            app.BalanceLabel.Text = 'Balance: $10000';
            app.StatusLabel = uilabel(app.BLACKJACKMANANDMACHINEPanel);
            app.StatusLabel.Text = '';
            app.Image = uiimage(app.BLACKJACKMANANDMACHINEPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Blackjack table.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end