classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BlackjackManandMachinePanel  matlab.ui.container.Panel
        ResumeButton                 matlab.ui.control.Button
        StatusLabel                  matlab.ui.control.Label
        EStopToggleButton            matlab.ui.control.StateButton
        BalanceTextBox               matlab.ui.control.NumericEditField
        GameWindowTextbox            matlab.ui.control.TextArea
        GameWindowTextAreaLabel      matlab.ui.control.Label
        StartGameButton              matlab.ui.control.Button
        DealerHandTextbox            matlab.ui.control.TextArea
        DealerTextAreaLabel          matlab.ui.control.Label
        PlayerHandTextbox            matlab.ui.control.TextArea
        PlayerTextAreaLabel          matlab.ui.control.Label
        BalanceLabel                 matlab.ui.control.Label
        BetAmountTextbox             matlab.ui.control.NumericEditField
        PlaceBetButton               matlab.ui.control.Button
        StandButton                  matlab.ui.control.Button
        HitButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        Game % Instance of BlackjackTest class
        robots % Constructor for controller class
    end
    methods (Access = public)
        % Function to start or reset the game
        function startGame(app)
            app.Game = BlackjackTest();  % Initialize the Blackjack game
            app.robots = Controller();   % Initialize the Controller
            app.BetAmountTextbox.Value = 0;  % Reset bet amount field to 0
            app.updateGameWindow();  % Ensure UI reflects the new game state
        end

        % Callback for Place Bet button
        function placeBet(app)
            betAmount = app.BetAmountTextbox.Value;  % Get the bet amount
            currentBalance = app.BalanceTextBox.Value;  % Get current balance
            if betAmount <= 0 || betAmount > currentBalance
                uialert(app.UIFigure, 'Invalid bet amount!', 'Bet Error');
                return;
            end
            app.Game.betAmount = betAmount;
            app.Game.balance = currentBalance - betAmount;  % Deduct the bet from the balance

            % Start the game here if the bet is valid
            app.startGame();  % Initialize the Blackjack game
            app.robots = Controller();   % Initialize the Controller

            % Deal initial cards
            [app.Game.playerHand, app.Game] = app.Game.hitCard('player', 2);  % Deal 2 cards to player
            [app.Game.dealerHand, app.Game] = app.Game.hitCard('dealer', 2);  % Deal 2 cards to dealer

            % Update the UI to reflect the new game state
            app.updateGameWindow();
            app.checkBust();  % Check if the player has busted after dealing
        end

        % Callback for Hit button
        function hit(app)
            % Deal one card to the player
            [app.Game.playerHand, app.Game] = app.Game.hitCard('player', 1);
            app.updateGameWindow();  % Update the UI after hitting

            % Check if the player has busted
            if app.Game.calculateTotal(app.Game.playerHand) > 21
                app.updateGameWindow('Bust! Dealer Wins!');
            end
        end

        % Callback for Stand button

        function stand(app)
            % Trigger the dealer's turn in the BlackjackTest class
            app.Game = app.Game.onDealerTurn();

            % Update the app's UI after the dealer's turn
            app.updateGameWindow();

            % Get the totals to determine the winner
            playerTotal = app.Game.calculateTotal(app.Game.playerHand);
            dealerTotal = app.Game.calculateTotal(app.Game.dealerHand);

            % Display the winner message
            app.declareOutcome(app.Game.gameWindow);
        end

        % Function to update UI elements
        function updateGameWindow(app)
            app.BalanceTextBox.Value = app.Game.balance;  % Update balance

            % Update player's hand and total
            playerHandStr = ['Your cards: ', app.Game.handToString(app.Game.playerHand), ...
                ' | Total: ', num2str(app.Game.calculateTotal(app.Game.playerHand))];
            app.PlayerHandTextbox.Value = playerHandStr;

            % Update dealer's hand and total
            dealerHandStr = ['Dealer''s cards: ', app.Game.handToString(app.Game.dealerHand), ...
                ' | Total: ', num2str(app.Game.calculateTotal(app.Game.dealerHand))];
            app.DealerHandTextbox.Value = dealerHandStr;

            % Update game window messages
            app.GameWindowTextbox.Value = app.Game.gameWindow;
        end

        % Function to declare outcome and reset game
        function declareOutcome(app, message)
            % uialert(app.UIFigure, message, 'Game Over');
            % app.startGame(); % Reset game for a new round
        end

        function checkBust(app)
            if app.Game.calculateTotal(app.Game.playerHand) > 21
                app.declareOutcome('Bust! Dealer Wins!');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.hit();  % Call hit method directly
            app.robots.HitMe(app.robots);
            app.robots.DealCard(app.robots);
        end

        % Button pushed function: PlaceBetButton
        function PlaceBetButtonPushed(app, event)
            try
                app.placeBet();  % Attempt to place bet
                app.robots.PlayerBet(app.robots);
                app.robots.PickUpChipDealer(app.robots);

            catch ME
                % uialert(app.UIFigure, ME.message, 'Error in placing bet');
                disp(ME.stack);  % Display stack trace for debugging
            end
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            app.stand();
            % Update any relevant UI elements, for example:
            app.GameWindowTextbox.Value = app.Game.gameWindow;  % Display game messages
            app.robots.Stand(app.robots);

        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
    % Ensure bet amount and balance are scalars
    % betAmount = app.Game.betAmount;  % Get the current bet amount
    % balance = app.BalanceTextBox.Value;  % Get the current balance

    % Only start the game if it's not already started
    % if ~app.Game.gameStarted  % Check if the game has already started
    %     % Check if a valid bet has been placed before starting the game
    %     if (isscalar(betAmount) && (betAmount <= 0 || betAmount > balance))
    %         uialert(app.UIFigure, 'Please place a valid bet before starting the game!', 'Bet Error');
    %         return;
    %     end

        app.Game.gameStarted = true;  % Set game state to started
        app.startGame();  % Initialize and start the game
        app.robots.DealCard(app.robots);
        app.EStopToggleButton.Value = false;  % Turn off the E-Stop toggle
        app.EStopToggleButton.Enable = 'on';   % Enable the toggle button
        app.StatusLabel.Text = 'Robot dealing cards';
    % else
    %     uialert(app.UIFigure, 'Game is already in progress!', 'Game Error');
    % end

                uialert(app.UIFigure, 'Game is already in progress!', 'Game Error');

        end

        % Value changed function: PlayerHandTextbox
        function PlayerHandTextboxValueChanged(app, event)
            app.Game = app.PlayerHandTextbox.Value;
        end

        % Value changed function: DealerHandTextbox
        function DealerHandTextboxValueChanged(app, event)
            app.Game = app.DealerHandTextbox.Value;
        end

        % Value changed function: EStopToggleButton
        function EStopToggleButtonValueChanged(app, event)
            if app.EStopToggleButton.Enable
                value = app.EStopToggleButton.Value; % Get the toggle button value
                % Update the label based on the toggle button's state
                if value  % If the toggle button is activated
                    app.StatusLabel.Text = 'Robot standing-by!';
                    uialert(app.UIFigure, 'E-Stop engaged! ', 'Saftey Alert');
                    %disp('E-Stop engaged! ');
                    % while value
                    %     app.robots.GuiEStop(1)
                    %     disp('E-Stop ');
                    %     if app.EStopToggleButton.Disable 
                    %         value = app.EStopToggleButton.Value;
                    %         disp('E-Stop  disegnaged ');
                    %     end
                    % end
                else  % If the toggle button is deactivated
                    uialert(app.UIFigure, 'E-Stop disengaged! Press ''Resume'' to continue motion!', 'Saftey Alert');
                end
            else
                % If the toggle is disabled, reset its value to the previous state
                app.EStopToggleButton.Value = false; % Keep it off
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            % Logic to resume the robot's motion
            app.StatusLabel.Text = 'Robot resuming motion...';
            % You can call a method from your Controller or whatever logic you have to resume motion
            % e.g., app.robots.ResumeMotion(app.robots);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BlackjackManandMachinePanel = uipanel(app.UIFigure);
            app.BlackjackManandMachinePanel.Title = 'Blackjack: Man and Machine';
            app.HitButton = uibutton(app.BlackjackManandMachinePanel, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.BlackjackManandMachinePanel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.PlaceBetButton = uibutton(app.BlackjackManandMachinePanel, 'push');
            app.PlaceBetButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceBetButtonPushed, true);
            app.PlaceBetButton.Text = 'Place Bet';
            app.BetAmountTextbox = uieditfield(app.BlackjackManandMachinePanel, 'numeric');
            app.BetAmountTextbox.AllowEmpty = 'on';
            app.BetAmountTextbox.Placeholder = '0';
            app.BetAmountTextbox.Value = [];
            app.BalanceLabel = uilabel(app.BlackjackManandMachinePanel);
            app.BalanceLabel.Text = 'Balance: ';
            app.PlayerTextAreaLabel = uilabel(app.BlackjackManandMachinePanel);
            app.PlayerTextAreaLabel.Text = 'Player';
            app.PlayerHandTextbox = uitextarea(app.BlackjackManandMachinePanel);
            app.PlayerHandTextbox.ValueChangedFcn = createCallbackFcn(app, @PlayerHandTextboxValueChanged, true);
            app.DealerTextAreaLabel = uilabel(app.BlackjackManandMachinePanel);
            app.DealerTextAreaLabel.Text = 'Dealer';
            app.DealerHandTextbox = uitextarea(app.BlackjackManandMachinePanel);
            app.DealerHandTextbox.ValueChangedFcn = createCallbackFcn(app, @DealerHandTextboxValueChanged, true);
            app.StartGameButton = uibutton(app.BlackjackManandMachinePanel, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.GameWindowTextAreaLabel = uilabel(app.BlackjackManandMachinePanel);
            app.GameWindowTextAreaLabel.Text = 'Game Window';
            app.GameWindowTextbox = uitextarea(app.BlackjackManandMachinePanel);
            app.BalanceTextBox = uieditfield(app.BlackjackManandMachinePanel, 'numeric');
            app.BalanceTextBox.Value = 500;
            app.EStopToggleButton = uibutton(app.BlackjackManandMachinePanel, 'state');
            app.EStopToggleButton.ValueChangedFcn = createCallbackFcn(app, @EStopToggleButtonValueChanged, true);
            app.EStopToggleButton.Text = 'E-Stop (Toggle)';
            app.StatusLabel = uilabel(app.BlackjackManandMachinePanel);
            app.StatusLabel.Text = 'Robot ready to start';
            app.ResumeButton = uibutton(app.BlackjackManandMachinePanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end