classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        CurrentBetEditField       matlab.ui.control.NumericEditField
        CurrentBetEditFieldLabel  matlab.ui.control.Label
        BalanceEditField          matlab.ui.control.NumericEditField
        BalanceEditFieldLabel     matlab.ui.control.Label
        StandButton               matlab.ui.control.Button
        HitMeButton               matlab.ui.control.Button
        EStopToggleButton         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)

            % Check if the player already has 5 cards
            if length(app.playerHand) >= 5
                uialert(app.UIFigure, 'You already have 5 cards. No more cards can be drawn.', 'Max Cards Reached');
                return;
            end

            % Deal one card to the player's hand
            [app.playerHand, app] = app.dealCard(app.playerHand);

            % Update the display with the player's hand and total
            app.updatePlayerHandDisplay();

            % Check for bust (if hand total exceeds 21)
            if app.calculateTotal(app.playerHand) > 21
                uialert(app.UIFigure, 'Bust! You exceeded 21.', 'Game Over');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EStopToggleButton = uibutton(app.UIFigure, 'state');
            app.EStopToggleButton.Text = 'E-Stop (Toggle)';
            app.HitMeButton = uibutton(app.UIFigure, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Text = 'Hit Me';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.Text = 'Stand';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentBetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentBetEditFieldLabel.Text = 'Current Bet';
            app.CurrentBetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end