classdef guitutorial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GUIPlaygroundPanel         matlab.ui.container.Panel
        LampSwitch                 matlab.ui.control.Lamp
        Switch                     matlab.ui.control.Switch
        SwitchLabel                matlab.ui.control.Label
        SensorInputEditField       matlab.ui.control.NumericEditField
        SensorInputEditFieldLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        LampSensor                 matlab.ui.control.Lamp
        WeightNEditField           matlab.ui.control.NumericEditField
        WeightNEditFieldLabel      matlab.ui.control.Label
        Accms2EditField            matlab.ui.control.NumericEditField
        Accms2EditFieldLabel       matlab.ui.control.Label
        MasskgEditField            matlab.ui.control.NumericEditField
        MasskgEditFieldLabel       matlab.ui.control.Label
        LampFMA                    matlab.ui.control.Lamp
        CalculateButton            matlab.ui.control.Button
        EditField_4                matlab.ui.control.EditField
        DisplayButton_2            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function DisplayButtonPushed(app, event)
            %% Callback means something will happen when the button is pressed

            app.EditField.Value = 'Hello World!';

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            mass = app.MasskgEditField.Value;
            acc = app.Accms2EditField.Value;

            % Newton's Second Law F=ma
            Force = mass * acc;

            app.WeightNEditField.Value = Force;

            %% Using a Lamp Loop, really great for RMC (velocity potentially)
            if Force > 0  && Force <= 500;
                app.Lamp.Color = 'g' % Green
            elseif Force > 500 && Force <= 1000
                app.Lamp.Color = '0.93,0.69,0.13' % Orange
            else
                app.Lamp.Color = 'red' % Red
            end
        end

        % Value changed function: SensorInputEditField
        function SensorInputEditFieldValueChanged(app, event)
            value = app.SensorInputEditField.Value;

            % 0 Not Active
            % 1 Active
            % 2 Error!

            switch value
                case 0
                    app.Lamp_2.Color = '0.50,0.50,0.50' % Grey
                    app.Label.Text = 'Not Active';
                case 1
                    app.Lamp_2.Color = 'green' % Green
                    app.Label.Text = 'Not Active';
                case 2
                    app.Lamp_2.Color = 'r' % Red
                    app.Label.Text = 'Error!';
                otherwise
                    app.Lamp_2.Color = [0 0 0] % Black
                    app.Label.Text = 'State Unknown!';
            end


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
           % Great for the e-stop
            value = app.Switch.Value;

            if strcmp(value, 'On')
                app.LampSwitch.Color = 'g';
            else 
                app.LampSwitch.Color = 'r';
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GUIPlaygroundPanel = uipanel(app.UIFigure);
            app.GUIPlaygroundPanel.Title = 'GUI Playground';
            app.DisplayButton_2 = uibutton(app.GUIPlaygroundPanel, 'push');
            app.DisplayButton_2.Text = 'Display';
            app.EditField_4 = uieditfield(app.GUIPlaygroundPanel, 'text');
            app.CalculateButton = uibutton(app.GUIPlaygroundPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LampFMA = uilamp(app.GUIPlaygroundPanel);
            app.MasskgEditFieldLabel = uilabel(app.GUIPlaygroundPanel);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.GUIPlaygroundPanel, 'numeric');
            app.Accms2EditFieldLabel = uilabel(app.GUIPlaygroundPanel);
            app.Accms2EditFieldLabel.Text = 'Acc (m/s^2)';
            app.Accms2EditField = uieditfield(app.GUIPlaygroundPanel, 'numeric');
            app.WeightNEditFieldLabel = uilabel(app.GUIPlaygroundPanel);
            app.WeightNEditFieldLabel.Text = 'Weight (N)';
            app.WeightNEditField = uieditfield(app.GUIPlaygroundPanel, 'numeric');
            app.LampSensor = uilamp(app.GUIPlaygroundPanel);
            app.Label = uilabel(app.GUIPlaygroundPanel);
            app.Label.Text = '';
            app.SensorInputEditFieldLabel = uilabel(app.GUIPlaygroundPanel);
            app.SensorInputEditFieldLabel.Text = 'Sensor Input';
            app.SensorInputEditField = uieditfield(app.GUIPlaygroundPanel, 'numeric');
            app.SensorInputEditField.ValueChangedFcn = createCallbackFcn(app, @SensorInputEditFieldValueChanged, true);
            app.SwitchLabel = uilabel(app.GUIPlaygroundPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.GUIPlaygroundPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.LampSwitch = uilamp(app.GUIPlaygroundPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guitutorial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end